classdef CONTROL_TESTING_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope           matlab.ui.scope.TimeScope
        VariableTuner       simulink.ui.control.VariableTuner
        Knob                matlab.ui.control.Knob
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 5];
            app.Knob.MajorTicks = [0 1 2 3 4 5];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.MinorTicks = [0 1 2 3 4 5 6];
            app.VariableTuner = uisimvartuner(app.UIFigure);
            app.VariableTuner.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'PS-Simulink Converter:1'; 'Subsystem:2'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'PS-Simulink Converter:1, Subsystem:2';
            app.TimeScope.YLabel = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Knob, 'Value', app.Simulation.TunableVariables, 'C');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/PS-Simulink Converter:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Subsystem:2'], app.TimeScope);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONTROL_TESTING_APP

            % Associate the Simulink Model
            app.Simulation = simulation('CONTROL_TESTING');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end